classdef autoseg_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PreviewImageLabel         matlab.ui.control.Label
        InformationPanel          matlab.ui.container.Panel
        TabGroup2                 matlab.ui.container.TabGroup
        ImageTab                  matlab.ui.container.Tab
        Panel                     matlab.ui.container.Panel
        Panel_2                   matlab.ui.container.Panel
        withLabel                 matlab.ui.control.Label
        EditField_7               matlab.ui.control.EditField
        WormLabel                 matlab.ui.control.Label
        EditField_6               matlab.ui.control.EditField
        xLabel                    matlab.ui.control.Label
        ImageDimsLabel            matlab.ui.control.Label
        EditField_3               matlab.ui.control.NumericEditField
        EditField_2               matlab.ui.control.NumericEditField
        MaxProjectionButton       matlab.ui.control.StateButton
        zSlicesListBox            matlab.ui.control.ListBox
        zSlicesListBoxLabel       matlab.ui.control.Label
        NeuronsTab                matlab.ui.container.Tab
        SegCount                  matlab.ui.control.NumericEditField
        IdCount                   matlab.ui.control.NumericEditField
        SegmentedneuronssuccesfullyIDd75neuronsLabel  matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        CreditTab                 matlab.ui.container.Tab
        LaboratoryPageButton      matlab.ui.control.Button
        GithubRepositoryButton    matlab.ui.control.Button
        OriginalPaperButton       matlab.ui.control.Button
        CiteassuchLabel           matlab.ui.control.Label
        Segmentationrelieson3DeeCellTrackeraLabel  matlab.ui.control.Label
        TextArea                  matlab.ui.control.TextArea
        Slider                    matlab.ui.control.Slider
        ButtonGroup               matlab.ui.container.ButtonGroup
        CellFilterLabel           matlab.ui.control.Label
        ZScalingEditField         matlab.ui.control.NumericEditField
        ZScalingEditFieldLabel    matlab.ui.control.Label
        EditField                 matlab.ui.control.NumericEditField
        EditFieldLabel            matlab.ui.control.Label
        VoxelResolutionEditField  matlab.ui.control.NumericEditField
        MinimumSizeLabel          matlab.ui.control.Label
        AveragenoncellintensityEditField  matlab.ui.control.NumericEditField
        AveragenoncellintensityEditFieldLabel  matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        SettingsButtonGroup       matlab.ui.container.ButtonGroup
        NoiseFilterButtonGroup    matlab.ui.container.ButtonGroup
        ManualButton              matlab.ui.control.RadioButton
        AutoButton                matlab.ui.control.RadioButton
        HoveroverlabelsforexplanationLabel  matlab.ui.control.Label
        DisplayButtonGroup        matlab.ui.container.ButtonGroup
        OverlayButton             matlab.ui.control.RadioButton
        SliderButton              matlab.ui.control.RadioButton
        RESETButton               matlab.ui.control.Button
        BlackOutToolButton        matlab.ui.control.Button
        HistogramToolButton       matlab.ui.control.Button
        GENERATEButton            matlab.ui.control.Button
        CellFilterButtonGroup     matlab.ui.container.ButtonGroup
        NeuronCountButton         matlab.ui.control.RadioButton
        MinimumSizeButton         matlab.ui.control.RadioButton
        OverlayAxes               matlab.ui.control.UIAxes
        ImageAxes                 matlab.ui.control.UIAxes
        SegAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        live_view; %
        parent_app; % Reference to main Neuropal window.
        image_data; % Passed from main
        image_neurons;
        repo_img;
        repo_view;
        image_gamma; % Passed from main
        image_view; % Passed from main
        avg_nci;
    end

    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function UpdateFromChild(app, image_data, image_neurons,image_gamma)
            app.image_data = image_data;
            app.image_gamma = image_gamma;
            app.DrawImageData()
        end

        function CalcNCI(app)
            width = size(app.image_view,2);
            height = size(app.image_view,1);
            top_left = mean(app.image_data(floor(height-height/10):height,1:floor(width/10)),'all');
            top_right = mean(app.image_view(floor(height-height/10):height, floor(width-width/10):width),'all');
            bot_left = mean(app.image_view(1:floor(height/10),1:floor(width/10)),'all');
            bot_right = mean(app.image_view(1:floor(height/10),floor(width-width/10):width),'all');
            app.avg_nci = mean([top_left top_right bot_left bot_right],'all');
            app.AveragenoncellintensityEditField.Value = app.avg_nci;
        end

        function DrawImageData(app)

            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            % Determine the color channel indices.
            red = str2double(app.parent_app.RDropDown.Value);
            green = str2double(app.parent_app.GDropDown.Value);
            blue = str2double(app.parent_app.BDropDown.Value);
            white = str2double(app.parent_app.WDropDown.Value);
            dic = str2double(app.parent_app.DICDropDown.Value);
            gfp = str2double(app.parent_app.GFPDropDown.Value);

            % Determine the channel=color assignments for displaying.
            color_indices = [red, green, blue];

            % Draw the 3 color channels.
            app.image_view = app.image_data(:,:,:,color_indices);

            % Add in the white channel.
            if app.parent_app.WCheckBox.Value % White

                % Compute the white channel.
                wchannel = app.image_data(:,:,:,white);

                % Adjust the gamma.
                W_i = app.parent_app.gamma_RGBW_DIC_GFP_index(4); % white channel gamma index
                if length(app.image_gamma) >= W_i && app.image_gamma(W_i) ~= 1
                    wchannel = imadjustn(wchannel,[],[], app.image_gamma(W_i));
                end

                % Add the white channel.
                app.image_view = app.image_view + repmat(wchannel, [1,1,1,3]);
            end

            % Add in the DIC channel.
            if app.parent_app.DICCheckBox.Value % DIC

                % Compute the DIC channel.
                dic_channel = app.image_data(:,:,:,dic);

                % Adjust the gamma.
                DIC_i = app.parent_app.gamma_RGBW_DIC_GFP_index(5); % DIC channel gamma index
                if length(app.image_gamma) >= DIC_i && app.image_gamma(DIC_i) ~= 1
                    dic_channel = imadjustn(dic_channel,[],[], app.image_gamma(DIC_i));
                end

                % Add the DIC channel.
                app.image_view = app.image_view + repmat(dic_channel, [1,1,1,3]);
            end

            % Add in the GFP channel.
            if app.parent_app.GFPCheckBox.Value % GFP

                % Compute the GFP channel.
                gfp_color = Program.GUIPreferences.instance().GFP_color;
                gfp_channel = app.image_data(:,:,:,gfp);

                % Adjust the gamma.
                GFP_i = app.parent_app.gamma_RGBW_DIC_GFP_index(6); % GFP channel gamma index
                if length(app.image_gamma) >= GFP_i && app.image_gamma(GFP_i) ~= 1
                    gfp_channel = imadjustn(gfp_channel,[],[], app.image_gamma(GFP_i));
                end

                % Add the GFP channel.
                gfp_channel = repmat(gfp_channel, [1,1,1,3]);
                gfp_channel(:,:,:,~gfp_color) = 0;
                app.image_view = app.image_view + gfp_channel;
            end

            % Adjust the gamma.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.
            app.image_view = uint16(double(intmax('uint16')) * ...
                double(app.image_view)/double(max(app.image_view(:))));
            for c = 1:size(app.image_view, 4)
                if app.image_gamma(c) ~= 1
                    app.image_view(:,:,:,c) = ...
                        imadjustn(squeeze(app.image_view(:,:,:,c)),[],[], ...
                        app.image_gamma(c));
                end
            end

            app.image_view = double(app.image_view)/double(max(app.image_view(:)));
            image(app.ImageAxes, squeeze(max(app.image_view,[],3)));

            switch app.NoiseFilterButtonGroup.SelectedObject.Text
                case 'Auto'
                    app.CalcNCI()
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, image_data, image_neurons, image_view, image_gamma)
            
            app.parent_app = parent_app;
            app.image_data = image_data;
            app.repo_img = image_data;
            app.image_neurons = image_neurons;
            app.image_view = image_view;
            app.repo_view = image_view;
            app.image_gamma = image_gamma;
    
            % Configure image axes
            %app.ImageAxes.Visible = 'off';

            C = {};

            for n = 1:size(app.image_data, 3)
                C{n} = ['Slice ' num2str(n)];
            end

            app.zSlicesListBox.Items = C;
            app.zSlicesListBox.ItemsData = 1:size(app.image_data,3);
            app.zSlicesListBox.Value = 1:size(app.image_data,3);

            axis(app.ImageAxes, 'normal');
            image(app.ImageAxes, squeeze(max(app.image_view,[],3)));

            app.CalcNCI()
    
            app.Slider.Limits = [0,896];
            app.Slider.Value = 896/2;
            app.ImageAxes.XLim = [0, app.Slider.Value];
            app.ImageAxes.Position = [43,307,app.Slider.Value,233];
            app.OverlayAxes.Position = [43,307,app.Slider.Value,233];
            app.SegAxes.Position = [43,307,896,233];
            set(app.SegAxes,'Color','#787878')
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.ImageAxes.XLim = [0, event.Value];
            app.ImageAxes.Position = [app.ImageAxes.Position(1),app.ImageAxes.Position(2),event.Value,app.ImageAxes.Position(4)];
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.ImageAxes.XLim = [0, event.Value];
            app.ImageAxes.Position = [app.ImageAxes.Position(1),app.ImageAxes.Position(2),app.Slider.Value,app.ImageAxes.Position(4)];
        end

        % Selection changed function: CellFilterButtonGroup
        function CellFilterButtonGroupSelectionChanged(app, event)
            switch app.CellFilterButtonGroup.SelectedObject.Text
                case 'Minimum Size'
                    app.CellFilterLabel.Text = 'Minimum Size:';
                case 'Neuron Count'
                    app.CellFilterLabel.Text = 'Expected Count:';
            end
        end

        % Selection changed function: NoiseFilterButtonGroup
        function NoiseFilterButtonGroupSelectionChanged(app, event)
            switch app.NoiseFilterButtonGroup.SelectedObject.Text
                case 'Auto'
                    set(app.AveragenoncellintensityEditField,'Editable','off')
                    app.CalcNCI()
                case 'Manual'
                    set(app.AveragenoncellintensityEditField,'Editable','on')
            end
        end

        % Button pushed function: OriginalPaperButton
        function OriginalPaperButtonPushed(app, event)
            web('https://elifesciences.org/articles/59187')
        end

        % Button pushed function: GithubRepositoryButton
        function GithubRepositoryButtonPushed(app, event)
            web('https://github.com/WenChentao/3DeeCellTracker')
        end

        % Button pushed function: LaboratoryPageButton
        function LaboratoryPageButtonPushed(app, event)
            web('https://en.kokimura-lab.org/')
        end

        % Button pushed function: HistogramToolButton
        function HistogramToolButtonPushed(app, event)
            histogram_window(app, app.image_data, app.image_neurons, app.image_view, app.image_gamma, Program.GUIPreferences.instance(), app.parent_app.RDropDown.Value, app.parent_app.GDropDown.Value, app.parent_app.BDropDown.Value, app.parent_app.WDropDown.Value, app.parent_app.DICDropDown.Value, app.parent_app.GFPDropDown.Value);
        end

        % Button pushed function: GENERATEButton
        function GENERATEButtonPushed(app, event)
            check = uiconfirm(app.UIFigure,'Depending on your computer, this may take a while. Are you sure you want to proceed?','Warning!','Options',{'Yes','No'},'DefaultOption','Yes');
            target_data = app.image_data(:,:,min(app.zSlicesListBox.Value):max(app.zSlicesListBox.Value),:,:);
            switch check
                case 'Yes'
                    data_instance = app.image_data;
                    save('cache.mat','data_instance')
                    input = sprintf('segmentation.py  %d %d %d %d %d %d', size(app.image_data,1), size(app.image_data,2), app.ZScalingEditField.Value, app.VoxelResolutionEditField.Value, app.AveragenoncellintensityEditField.Value,app.EditField.Value)
                    
                    %modelfile = 'ffn_pretrained.onnx';
                    %weights = 'weights_initial.h5';

                    %net = importONNXLayers(modelfile);
                    %analyzeNetwork(net)

                    output = pyrunfile(input,'output');
                    output.type
                    output.sz
                    output.type_sz
                    %{
                    uialert(app.UIFigure,'Segmentation done!','NeuroPAL ID')
                    image(app.SegAxes,output.segout)
                    image(app.OverlayAxes,imfuse(squeeze(max(app.image_view,[],3)),output.segout))
                    app.SegCount.Value = output.segcount;
                    %app.IdCount.Value = output.idcount;

                    for n = 1:size(output.neurons)
                        neuron = output.neurons(n);
                        app.UITable(n) = {neuron.name,neuron.id,neuron.confidence,neuron.position};
                    end
                    %}
                    
                case 'No'
                    return
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.image_data = app.repo_img;
            app.image_view = app.repo_view;
            image(app.ImageAxes, squeeze(max(app.image_view,[],3)));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: BlackOutToolButton
        function BlackOutToolButtonPushed(app, event)
            check = uiconfirm(app.UIFigure,'There are two available black out modes. Regular black out allows you to select a region to be nulled out before segmentation. Inverse mode allows you to select a region to be segmented.','Select Mode','Options',{'Regular Mode','Inverse Mode'},'DefaultOption','Regular Mode');

            app.Slider.Value = app.Slider.Limits(2);
            app.ImageAxes.XLim = [0, app.Slider.Value];
            app.ImageAxes.Position = [43,307,app.Slider.Value,233];

            uiwait(msgbox('Click and drag your cursor on the image to select the region to ignore during segmentation.','Instructions'))
            
            roi = drawfreehand(app.ImageAxes,'Color','black','StripeColor','m');
            draw(roi)
            mask = createMask(roi,app.image_view);

            switch check
                case 'Regular Mode'
                    mask = ~mask;
                    mask = double(mask);
        
                case 'Inverse Mode'
                    
                    mask = double(mask);
            end
        
            for n = 1:size(app.image_data,3)
                color_indices = [1, 2, 3];
                app.image_data(:,:,:,color_indices) = double(app.image_data(:,:,:,color_indices)).*mask;
            end
    
            image(app.ImageAxes, squeeze(max(app.image_view,[],3)).*mask);
        end

        % Clicked callback: zSlicesListBox
        function zSlicesListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            app.MaxProjectionButton.Value = 0;
        end

        % Value changed function: zSlicesListBox
        function zSlicesListBoxValueChanged(app, event)
            image(app.ImageAxes, squeeze(app.image_view(:,:,app.zSlicesListBox.Value,:,:)));
            app.MaxProjectionButton.Value = 0;
        end

        % Value changed function: MaxProjectionButton
        function MaxProjectionButtonValueChanged(app, event)
            if app.MaxProjectionButton.Value == 1
                image(app.ImageAxes, squeeze(max(app.image_view,[],3)));
                app.zSlicesListBox.Value = 1:size(app.image_data,3);
            else
                image(app.ImageAxes, squeeze(app.image_view(:,:,app.zSlicesListBox.Value,:,:)));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SegAxes = uiaxes(app.UIFigure);
            app.SegAxes.XTick = [];
            app.SegAxes.XTickLabel = '';
            app.SegAxes.YTick = [];
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = '';
            app.ImageAxes.YTick = [];
            app.OverlayAxes = uiaxes(app.UIFigure);
            app.OverlayAxes.XTick = [];
            app.OverlayAxes.XTickLabel = '';
            app.OverlayAxes.YTick = [];
            app.OverlayAxes.Visible = 'off';
            app.SettingsButtonGroup = uibuttongroup(app.UIFigure);
            app.SettingsButtonGroup.Title = 'Settings                                           ';
            app.CellFilterButtonGroup = uibuttongroup(app.SettingsButtonGroup);
            app.CellFilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CellFilterButtonGroupSelectionChanged, true);
            app.CellFilterButtonGroup.Title = 'Cell Filter';
            app.MinimumSizeButton = uiradiobutton(app.CellFilterButtonGroup);
            app.MinimumSizeButton.Text = 'Minimum Size';
            app.MinimumSizeButton.Value = true;
            app.NeuronCountButton = uiradiobutton(app.CellFilterButtonGroup);
            app.NeuronCountButton.Text = 'Neuron Count';
            app.GENERATEButton = uibutton(app.SettingsButtonGroup, 'push');
            app.GENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton.Text = 'GENERATE';
            app.HistogramToolButton = uibutton(app.SettingsButtonGroup, 'push');
            app.HistogramToolButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramToolButtonPushed, true);
            app.HistogramToolButton.Text = 'Histogram Tool';
            app.BlackOutToolButton = uibutton(app.SettingsButtonGroup, 'push');
            app.BlackOutToolButton.ButtonPushedFcn = createCallbackFcn(app, @BlackOutToolButtonPushed, true);
            app.BlackOutToolButton.Text = 'Black Out Tool';
            app.RESETButton = uibutton(app.SettingsButtonGroup, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.DisplayButtonGroup = uibuttongroup(app.SettingsButtonGroup);
            app.DisplayButtonGroup.Title = 'Display';
            app.SliderButton = uiradiobutton(app.DisplayButtonGroup);
            app.SliderButton.Text = 'Slider';
            app.SliderButton.Value = true;
            app.OverlayButton = uiradiobutton(app.DisplayButtonGroup);
            app.OverlayButton.Text = 'Overlay';
            app.HoveroverlabelsforexplanationLabel = uilabel(app.SettingsButtonGroup);
            app.HoveroverlabelsforexplanationLabel.Text = ' (Hover over labels for explanation.)';
            app.NoiseFilterButtonGroup = uibuttongroup(app.SettingsButtonGroup);
            app.NoiseFilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NoiseFilterButtonGroupSelectionChanged, true);
            app.NoiseFilterButtonGroup.Title = 'Noise Filter';
            app.AutoButton = uiradiobutton(app.NoiseFilterButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.AutoButton.Value = true;
            app.ManualButton = uiradiobutton(app.NoiseFilterButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.Label = uilabel(app.ButtonGroup);
            app.Label.Text = '';
            app.AveragenoncellintensityEditFieldLabel = uilabel(app.ButtonGroup);
            app.AveragenoncellintensityEditFieldLabel.Text = 'Average non-cell intensity:';
            app.AveragenoncellintensityEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.AveragenoncellintensityEditField.Editable = 'off';
            app.AveragenoncellintensityEditField.Tooltip = {'Averaged non-cell region intensity, which helps the program to ignore the background noises.'};
            app.MinimumSizeLabel = uilabel(app.ButtonGroup);
            app.MinimumSizeLabel.Text = 'Voxel Resolution:';
            app.VoxelResolutionEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.VoxelResolutionEditField.Tooltip = {'Resolution (um/voxels) ratio between z (depth) and x-y plane.'};
            app.EditFieldLabel = uilabel(app.ButtonGroup);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.EditField.Tooltip = {'Depending on cell filter, either the minimum cell size or the expected number of neurons. Used to help the program ignore small non-cell regions.'};
            app.ZScalingEditFieldLabel = uilabel(app.ButtonGroup);
            app.ZScalingEditFieldLabel.Text = 'Z-Scaling:';
            app.ZScalingEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.ZScalingEditField.Tooltip = {'Factor used to interpolate the unbalanced z_xy resolution.'};
            app.CellFilterLabel = uilabel(app.ButtonGroup);
            app.CellFilterLabel.Text = 'Minimum Size:';
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 100;
            app.InformationPanel = uipanel(app.UIFigure);
            app.InformationPanel.Title = 'Information';
            app.TabGroup2 = uitabgroup(app.InformationPanel);
            app.ImageTab = uitab(app.TabGroup2);
            app.ImageTab.Title = 'Image';
            app.zSlicesListBoxLabel = uilabel(app.ImageTab);
            app.zSlicesListBoxLabel.Text = 'z-Slices';
            app.zSlicesListBox = uilistbox(app.ImageTab);
            app.zSlicesListBox.Multiselect = 'on';
            app.zSlicesListBox.ValueChangedFcn = createCallbackFcn(app, @zSlicesListBoxValueChanged, true);
            app.zSlicesListBox.Tooltip = {'Click on single z-slice to preview or edit. Select multiple to limit segmentation to selection.'};
            app.zSlicesListBox.ClickedFcn = createCallbackFcn(app, @zSlicesListBoxClicked, true);
            app.zSlicesListBox.Value = {'Item 1'};
            app.MaxProjectionButton = uibutton(app.ImageTab, 'state');
            app.MaxProjectionButton.ValueChangedFcn = createCallbackFcn(app, @MaxProjectionButtonValueChanged, true);
            app.MaxProjectionButton.Text = 'Max Projection';
            app.MaxProjectionButton.Value = true;
            app.Panel = uipanel(app.ImageTab);
            app.EditField_2 = uieditfield(app.Panel, 'numeric');
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.Panel, 'numeric');
            app.EditField_3.Editable = 'off';
            app.ImageDimsLabel = uilabel(app.Panel);
            app.ImageDimsLabel.Text = 'Image Dims:';
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x';
            app.Panel_2 = uipanel(app.Panel);
            app.EditField_6 = uieditfield(app.Panel_2, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Value = 'Male';
            app.WormLabel = uilabel(app.Panel_2);
            app.WormLabel.Text = 'Worm';
            app.EditField_7 = uieditfield(app.Panel_2, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Value = 'Head';
            app.withLabel = uilabel(app.Panel_2);
            app.withLabel.Text = 'with';
            app.NeuronsTab = uitab(app.TabGroup2);
            app.NeuronsTab.Title = 'Neurons';
            app.UITable = uitable(app.NeuronsTab);
            app.UITable.ColumnName = {'Neuron'; 'Suspected ID'; 'Confidence (%)'; 'Position'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = [false false true false];
            app.UITable.Multiselect = 'off';
            app.SegmentedneuronssuccesfullyIDd75neuronsLabel = uilabel(app.NeuronsTab);
            app.SegmentedneuronssuccesfullyIDd75neuronsLabel.Text = 'Segmented              neurons, succesfully ID''d (75%+)              neurons.';
            app.IdCount = uieditfield(app.NeuronsTab, 'numeric');
            app.IdCount.Editable = 'off';
            app.SegCount = uieditfield(app.NeuronsTab, 'numeric');
            app.SegCount.Editable = 'off';
            app.CreditTab = uitab(app.TabGroup2);
            app.CreditTab.Title = 'Credit';
            app.TextArea = uitextarea(app.CreditTab);
            app.TextArea.Value = {'@article{'; 'author = {Wen, Chentao and Miura, Takuya and Voleti, Venkatakaushik and Yamaguchi, Kazushi and Tsutsumi, Motosuke and Yamamoto, Kei and Otomo, Kohei and Fujie, Yukako and Teramoto, Takayuki and Ishihara, Takeshi and Aoki, Kazuhiro and Nemoto, Tomomi and Hillman, Elizabeth MC and Kimura, Koutarou D},'; 'doi = {10.7554/eLife.59187},'; 'journal = {eLife},'; 'month = {mar},'; 'title = {{3DeeCellTracker, a deep learning-based pipeline for segmenting and tracking cells in 3D time lapse images}},'; 'volume = {10},'; 'year = {2021}'; '}'};
            app.Segmentationrelieson3DeeCellTrackeraLabel = uilabel(app.CreditTab);
            app.Segmentationrelieson3DeeCellTrackeraLabel.Text = 'Segmentation relies on a modified version of 3DeeCellTracker, a deep-learning based pipeline for tracking cells in 3D time lapse images of deforming/moving organs developed by Chentao Wen et al.';
            app.CiteassuchLabel = uilabel(app.CreditTab);
            app.CiteassuchLabel.Text = 'Cite as such:';
            app.OriginalPaperButton = uibutton(app.CreditTab, 'push');
            app.OriginalPaperButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalPaperButtonPushed, true);
            app.OriginalPaperButton.Text = 'Original Paper';
            app.GithubRepositoryButton = uibutton(app.CreditTab, 'push');
            app.GithubRepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @GithubRepositoryButtonPushed, true);
            app.GithubRepositoryButton.Text = 'Github Repository';
            app.LaboratoryPageButton = uibutton(app.CreditTab, 'push');
            app.LaboratoryPageButton.ButtonPushedFcn = createCallbackFcn(app, @LaboratoryPageButtonPushed, true);
            app.LaboratoryPageButton.Text = 'Laboratory Page';
            app.PreviewImageLabel = uilabel(app.UIFigure);
            app.PreviewImageLabel.Text = 'Preview Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = autoseg_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end