classdef histogram_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SettingsButtonGroup  matlab.ui.container.ButtonGroup
        RESETButton          matlab.ui.control.ToggleButton
        GammaCheckBox        matlab.ui.control.CheckBox
        BlueCheckBox         matlab.ui.control.CheckBox
        GreenCheckBox        matlab.ui.control.CheckBox
        RedCheckBox          matlab.ui.control.CheckBox
        BestFitButton        matlab.ui.control.ToggleButton
        MinMaxButton         matlab.ui.control.ToggleButton
        GammaSlider          matlab.ui.control.Slider
        GammaSliderLabel     matlab.ui.control.Label
        BlueSlider           matlab.ui.control.Slider
        BlueSliderLabel      matlab.ui.control.Label
        GreenSlider          matlab.ui.control.Slider
        GreenSliderLabel     matlab.ui.control.Label
        RedSlider            matlab.ui.control.Slider
        RedSliderLabel       matlab.ui.control.Label
        HTML                 matlab.ui.control.HTML
        ImageAxes            matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        wormImg % Worm image.
    end

    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            try
                im = imread(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end  
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxes, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                app.UIAxes.YLim = [0 maxcount];
                app.UIAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, wormImg)
            
            app.wormImg = wormImg;
            
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            updateimage(app, app.wormImg);
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Callback function
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Histogram')
            xlabel(app.UIAxes, 'Intensity')
            ylabel(app.UIAxes, 'Pixels')
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Worm Projection')
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = '';
            app.ImageAxes.YTick = [];
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = '<hr>';
            app.RedSliderLabel = uilabel(app.UIFigure);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.UIFigure);
            app.RedSlider.Value = 50;
            app.GreenSliderLabel = uilabel(app.UIFigure);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.UIFigure);
            app.GreenSlider.Value = 50;
            app.BlueSliderLabel = uilabel(app.UIFigure);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.UIFigure);
            app.BlueSlider.Value = 50;
            app.GammaSliderLabel = uilabel(app.UIFigure);
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.UIFigure);
            app.GammaSlider.Value = 50;
            app.SettingsButtonGroup = uibuttongroup(app.UIFigure);
            app.SettingsButtonGroup.Title = 'Settings';
            app.MinMaxButton = uitogglebutton(app.SettingsButtonGroup);
            app.MinMaxButton.Text = 'Min/Max';
            app.MinMaxButton.Value = true;
            app.BestFitButton = uitogglebutton(app.SettingsButtonGroup);
            app.BestFitButton.Text = 'Best Fit';
            app.RedCheckBox = uicheckbox(app.SettingsButtonGroup);
            app.RedCheckBox.Text = 'Red';
            app.RedCheckBox.Value = true;
            app.GreenCheckBox = uicheckbox(app.SettingsButtonGroup);
            app.GreenCheckBox.Text = 'Green';
            app.GreenCheckBox.Value = true;
            app.BlueCheckBox = uicheckbox(app.SettingsButtonGroup);
            app.BlueCheckBox.Text = 'Blue';
            app.BlueCheckBox.Value = true;
            app.GammaCheckBox = uicheckbox(app.SettingsButtonGroup);
            app.GammaCheckBox.Text = 'Gamma';
            app.GammaCheckBox.Value = true;
            app.RESETButton = uitogglebutton(app.SettingsButtonGroup);
            app.RESETButton.Text = 'RESET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = histogram_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end